classdef Project2Redesign_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportButton                    matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        DigitalBandwidthEditField       matlab.ui.control.NumericEditField
        DigitalBandwidthEditFieldLabel  matlab.ui.control.Label
        SecondDigitalCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        SecondDigitalCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        DigitalCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        DigitalCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        PlotDigitalFilterButton         matlab.ui.control.Button
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        DigitalFilterDesignDropDown     matlab.ui.control.DropDown
        DigitalFilterDesignDropDownLabel  matlab.ui.control.Label
        DigitalFilterTypeDropDown       matlab.ui.control.DropDown
        DigitalFilterTypeDropDownLabel  matlab.ui.control.Label
        ApplyDigitalFilterButton        matlab.ui.control.Button
        StopAudioButton                 matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        RecordAudioButton               matlab.ui.control.Button
        OrLabel                         matlab.ui.control.Label
        ChooseFileButton                matlab.ui.control.Button
        ApplyAnalogFilterButton         matlab.ui.control.Button
        AnalogFilterDesignDropDown      matlab.ui.control.DropDown
        AnalogFilterDesignDropDownLabel  matlab.ui.control.Label
        SecondAnalogCutoffFrequency     matlab.ui.control.NumericEditField
        SecondAnalogCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        StopBandAttentuation            matlab.ui.control.NumericEditField
        StopBandAttentuationEditFieldLabel  matlab.ui.control.Label
        AnalogCutoffFrequency           matlab.ui.control.NumericEditField
        AnalogCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        AnalogFilterTypeDropDown        matlab.ui.control.DropDown
        AnalogFilterTypeDropDownLabel   matlab.ui.control.Label
        AnalogOrderSpinner              matlab.ui.control.Spinner
        AnalogOrderLabel                matlab.ui.control.Label
        PlotAnalogFilterButton          matlab.ui.control.Button
        AnalogOrDigital                 matlab.ui.control.DropDown
        FilteredPlot                    matlab.ui.control.UIAxes
        FilteredPlotFFT                 matlab.ui.control.UIAxes
        InputSignalFFT                  matlab.ui.control.UIAxes
        InputSignal                     matlab.ui.control.UIAxes
        PlotPhase                       matlab.ui.control.UIAxes
        PlotMagnitude                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs = 44100; %set initial sampling frequency to 44100 for plotting FIR Filters 
        FilterNumerator; %b in the filter command
        FilterDenominator; %a in the filter command
        file; %the name of the opened file or to export a file
        filestatus; % tells us if the user imported a file or not
        signal; %THe input signal filters are applied to 
        fftsignal; %FFT of the input signal
        hb; % For the bode plot - G(jw)
        beforeFilter; %For the undo effect saves the input signal before  a filter is applied
    end
    
    methods (Access = private)
        
        function results = plotSignal(app) %Plot the input signal
            t = 0:1/app.Fs:length(app.signal)/app.Fs; %Convert from number of samples to time 
            t = t(1:length(t)-1); %Because matlab starts at 1 make it go from 0 to N-1
            plot(app.InputSignal,t ,app.signal); %plot it on the plot "Input Signal"
            
            app.fftsignal = FFTPlease(app.signal); %Take the fft of the signal 
            N = length(app.fftsignal);
            f = app.Fs*(0:(N/2))/N;
            f = f(1:end-1); %Convert from k to frequency
            stem(app.InputSignalFFT, f, abs(app.fftsignal(1:N/2))); %Plot the frequency 
        end
        
        function lowPassFilterImpulse = LowPassSincFilter(app, normalizedCutoffFrequency, M, window) 
            %Output is the impulse response for a low pass sinc filter
            %can be made into a high pass, band pass, and stop pass filter
            %normalized Cutoff Frequency should be between ([0, 1]) 
            %M is the number of samples, 4/BW
            %window is the type of window the user selected, ie: Blackman
            %harris
            
            n = 0:1:M-1; %N starting at 0 to M-1 points
            
            h = sin(2*pi*normalizedCutoffFrequency*(n - M/2))./(n-M/2); %The sinc function
            h((M/2)+1) = 2*pi*normalizedCutoffFrequency; %The halfway point must be the largest 
            h = h.*window; %The window is centered with the impulse response to take element wise multiplication
            Sum = 0;
            %Normalize the impulse response
            for i = 1:length(n)
                Sum = h(i) + Sum;
            end
            for i = 1:length(n)
            h(i) = h(i)/Sum; %so sum of all samples is equal to one
            end
            %Output the numerator for a sinc low pass filter
            lowPassFilterImpulse = h;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AnalogFilterTypeDropDown
        function AnalogFilterTypeDropDownValueChanged(app, event)
            %In the case analog filter is changed to band pass or band
            %stop, display the needed second frequency componenet
            value = app.AnalogFilterTypeDropDown.Value;
            if strcmp(value, "Band Pass") || strcmp(value, "Band Stop")
                app.SecondAnalogCutoffFrequency.Visible = "on";
                app.SecondAnalogCutoffFrequencyEditFieldLabel.Visible = "on";

            else
                app.SecondAnalogCutoffFrequency.Visible = "off";
                app.SecondAnalogCutoffFrequencyEditFieldLabel.Visible = "off";
            end
        end

        % Button pushed function: PlotAnalogFilterButton
        function PlotAnalogFilterButtonPushed(app, event)
            %Plots the frequency response of the filter, and gives the
            %numerator and denominator for the analog filter to be applied
            %with the input signal 

        order = app.AnalogOrderSpinner.Value; %Take order from user
        
        if strcmp(app.AnalogFilterTypeDropDown.Value, "Band Pass") %If its band pass there are 2 frequencies that must be inputted
            Fc = sort([app.AnalogCutoffFrequency.Value, app.SecondAnalogCutoffFrequency.Value], "ascend"); %Cut off frequency from lowest to highest
            
            %Checks the filter design type, if it is butterworth, besssel,
            %or chebyshev
            if strcmp(app.AnalogFilterDesignDropDown.Value, "Butterworth") 
                
                [bb, ab] = butter(order, 2*pi.*Fc, "bandpass", 's'); %bb is the numerator, ab is the denominator. 's' returns it as an analog filter
                [app.hb,wb] = freqs(bb,ab,4096); %take the analog frequency response and store the outputs
                semilogx(app.PlotMagnitude, wb/(2*pi), mag2db(abs(app.hb))); %Plot the magnitude vs frequency. wb/2pi is frequency and converts magntidue to db
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb))); %Degrees vs. omega
                app.FilterNumerator = bb; %Set the global functions to use numerator and denominator to apply the filter
                app.FilterDenominator = ab;

            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Bessel")
                [bb,ab] = besself(order,2*pi.*Fc, "bandpass"); %bb is the numerator, ab is the denominator. 's' returns it as an analog filter
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
                [app.hb, wb] = freqs(bb, ab, 4096); %take the analog frequency response and store the outputs
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb))); %Plot the magnitude vs frequency. wb/2pi is frequency and converts magntidue to db
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb))); %Use semilog to have x as a 10^x, and y as linear

            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Chebyshev I")
                [bb, ab] = cheby1(order, app.StopBandAttentuation.Value, 2*pi.*Fc, "bandpass", 's'); %bb is the numerator, ab is the denominator. 's' returns it as an analog filter
                [app.hb,wb] = freqs(bb,ab,4096);  %take the analog frequency response and store the outputs
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb))); %Plot the magnitude vs frequency. wb/2pi is frequency and converts magntidue to db
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;

            else %Chebyshev II
                
                [bb, ab] = cheby2(order, app.StopBandAttentuation.Value, 2*pi*Fc, "bandpass", 's'); %bb is the numerator, ab is the denominator. 's' returns it as an analog filter
                [app.hb,wb] = freqs(bb,ab,4096);  %take the analog frequency response and store the outputs
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb))); %Plot the magnitude vs frequency. wb/2pi is frequency and converts magntidue to db
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
            end 

        elseif strcmp(app.AnalogFilterTypeDropDown.Value, "Band Stop") %User selected band stop filter
            Fc = sort([app.AnalogCutoffFrequency.Value, app.SecondAnalogCutoffFrequency.Value], "ascend"); %Cut off frequency from lowest to highest
            if strcmp(app.AnalogFilterDesignDropDown.Value, "Butterworth")
               %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = butter(order, 2*pi.*Fc, 'stop', 's');
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, wb/(2*pi), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;

            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Bessel")
                %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb,ab] = besself(order,2*pi.*Fc, "stop");
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
                [app.hb, wb] = freqs(bb, ab, 4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));

            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Chebyshev I")
             %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = cheby1(order, app.StopBandAttentuation.Value, 2*pi.*Fc, "stop", 's');
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;

            else %Chebyshev II
            %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = cheby1(order, app.StopBandAttentuation.Value, 2*pi.*Fc, "stop", 's');
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
            end 

        else %High and Low Pass 
            Fc = app.AnalogCutoffFrequency.Value; %1 cutoff frequency 
            filtertype = app.AnalogFilterTypeDropDown.Value(1:regexp(app.AnalogFilterTypeDropDown.Value, "P")-2); %find out if the input is high or low pass by finding P, and subtrating it by two to get "Low" or "High"
            filtertype = lower(filtertype); %Input must be lower case

            if strcmp(app.AnalogFilterDesignDropDown.Value, "Butterworth")
                %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = butter(order, 2*pi*Fc, filtertype, 's');
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, wb/(2*pi), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                
                
            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Bessel")
                %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb,ab] = besself(order,2*pi*Fc, filtertype);
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
                [app.hb, wb] = freqs(bb, ab, 4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));

            elseif strcmp(app.AnalogFilterDesignDropDown.Value, "Chebyshev I")
               %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = cheby1(order, app.StopBandAttentuation.Value, 2*pi.*Fc, filtertype, 's');
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;

            else %Chebyshev II
                %bb, ab are the numerator and denominator used in apply the
               %filter. They are analog will be made digital
               %Plots the frequency response of the filter as well
                [bb, ab] = cheby2(order, app.StopBandAttentuation.Value, 2*pi.*Fc, filtertype, 's');
                [app.hb,wb] = freqs(bb,ab,4096);
                semilogx(app.PlotMagnitude, (wb/(2*pi)), mag2db(abs(app.hb)));
                semilogx(app.PlotPhase, wb/(2*pi), rad2deg(angle(app.hb)));
                app.FilterNumerator = bb;
                app.FilterDenominator = ab;
            end 
        end
        end

        % Callback function
        function AnalogFilterDesignValueChanged(app, event)
            %If the analog filter design drop down is changed to chebshev,
            %the stop band attentuation appears as an input
             if strcmp(value, "Chebyshev I") || strcmp(value, "Chebyshev II")
                app.StopBandAttentuation.Visible = "on";
                app.StopBandAttentuationEditFieldLabel.Visible = "on";
            else 
                app.StopBandAttentuation.Visible = "off";
                app.StopBandAttentuationEditFieldLabel.Visible = "off";
            end
        end

        % Value changed function: AnalogOrDigital
        function AnalogOrDigitalValueChanged(app, event)
            value = app.AnalogOrDigital.Value;
            %User changing the type of filter they want to analog or
            %digital changes what boxes are visible
            if strcmp(value, "Analog")

                %Turn off digital components
                app.DigitalBandwidthEditField.Visible = "off";
                app.DigitalBandwidthEditFieldLabel.Visible = "off";

                app.SecondDigitalCutoffFrequencyEditField.Visible = "off";
                app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = "off";

                app.DigitalCutoffFrequencyEditField.Visible = "off";
                app.DigitalCutoffFrequencyEditFieldLabel.Visible = "off";

                app.PlotDigitalFilterButton.Visible = "off";

                app.DigitalFilterDesignDropDown.Visible = "off";
                app.DigitalFilterDesignDropDownLabel.Visible = "off";

                app.DigitalFilterTypeDropDown.Visible = "off";
                app.DigitalFilterTypeDropDownLabel.Visible = "off";

                app.ApplyDigitalFilterButton.Visible = "off";
                
                app.WindowSizeEditField.Visible = "off";
                app.WindowSizeEditFieldLabel.Visible = "off";
                
                app.WindowTypeDropDown.Visible = "off";
                app.WindowTypeDropDownLabel.Visible = "off";
                %Turn on analog components
                app.ApplyAnalogFilterButton.Visible = "on";
                
                app.AnalogFilterDesignDropDown.Visible = "on";
                app.AnalogFilterDesignDropDownLabel.Visible = "on";
                
                %If the analog filter drop down was last on band pass or
                %band stop, show the second cutoff frequency
                if strcmp(app.AnalogFilterTypeDropDown.Value, "Band Pass") || strcmp(app.AnalogFilterTypeDropDown.Value, "Band Stop")
                app.SecondAnalogCutoffFrequency.Visible = "on";
                app.SecondAnalogCutoffFrequencyEditFieldLabel.Visible = "on";
                end
                %If the analog filter design drop down was on chebyshev
                %show the stopband attentuation 
                if strcmp(app.AnalogFilterDesignDropDown.Value, "Chebyshev I") || strcmp(app.AnalogFilterDesignDropDown.Value, "Chebyshev II")
                app.StopBandAttentuation.Visible = "on";
                app.StopBandAttentuationEditFieldLabel.Visible = "on";
                end
                

                %Turn on standard analog components
                app.AnalogCutoffFrequency.Visible = "on";
                app.AnalogCutoffFrequencyEditFieldLabel.Visible = "on";

                app.AnalogFilterTypeDropDown.Visible = "on";
                app.AnalogFilterTypeDropDownLabel.Visible = "on";

                app.AnalogOrderSpinner.Visible = "on";
                app.AnalogOrderLabel.Visible = "on";

                app.PlotAnalogFilterButton.Visible = "on";


            else %digital
                %Turn off analog Components

                app.ApplyAnalogFilterButton.Visible = "off";
                
                app.AnalogFilterDesignDropDown.Visible = "off";
                app.AnalogFilterDesignDropDownLabel.Visible = "off";

                app.AnalogOrderSpinner.Visible = "off";
                app.AnalogOrderLabel.Visible = "off";

                app.PlotAnalogFilterButton.Visible = "off";
                  app.StopBandAttentuation.Visible = "off";
                app.StopBandAttentuationEditFieldLabel.Visible = "off";

                app.SecondAnalogCutoffFrequency.Visible = "off";
                app.SecondAnalogCutoffFrequencyEditFieldLabel.Visible = "off";

                app.AnalogCutoffFrequency.Visible = "off";
                app.AnalogCutoffFrequencyEditFieldLabel.Visible = "off";

                app.AnalogFilterTypeDropDown.Visible = "off";
                app.AnalogFilterTypeDropDownLabel.Visible = "off";

                app.AnalogOrderSpinner.Visible = "off";
                app.AnalogOrderLabel.Visible = "off";

                app.PlotAnalogFilterButton.Visible = "off";

                %Turn on Digital Components
                app.PlotDigitalFilterButton.Visible = "on";
                %if it is moving average, only show moving average
                %components and turn off windowed-sinc components
                if strcmp(app.DigitalFilterDesignDropDown.Value, "Moving Average")
                    app.DigitalBandwidthEditField.Visible = "off";
                    app.DigitalBandwidthEditFieldLabel.Visible = "off";
    
                    app.SecondDigitalCutoffFrequencyEditField.Visible = "off";
                    app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = "off";
    
                    app.DigitalCutoffFrequencyEditField.Visible = "off";
                    app.DigitalCutoffFrequencyEditFieldLabel.Visible = "off";
    
                    app.PlotDigitalFilterButton.Visible = "on";
    
                    app.DigitalFilterDesignDropDown.Visible = "on";
                    app.DigitalFilterDesignDropDownLabel.Visible = "on";
    
                    app.DigitalFilterTypeDropDown.Visible = "off";
                    app.DigitalFilterTypeDropDownLabel.Visible = "off";
    
                    app.ApplyDigitalFilterButton.Visible = "on";
                    
                    app.WindowSizeEditField.Visible = "on";
                    app.WindowSizeEditFieldLabel.Visible = "on";
                    
                    app.WindowTypeDropDown.Visible = "off";
                    app.WindowTypeDropDownLabel.Visible = "off";
                else
                %If it is windowed-sinc turn off moving average components
                %and only showed windowed sinc

                %If it was last on band pass or band stop, show both cut
                %off frequencies
                    if(strcmp(app.DigitalFilterTypeDropDown.Value, "Band Pass") || strcmp(app.DigitalFilterTypeDropDown.Value, "Band Stop"))
                        app.SecondDigitalCutoffFrequencyEditField.Visible = "on";
                        app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = "on";
                    end

                    app.DigitalCutoffFrequencyEditField.Visible = "on";
                    app.DigitalCutoffFrequencyEditFieldLabel.Visible = "on";
    
                    app.PlotDigitalFilterButton.Visible = "on";
    
                    app.DigitalFilterDesignDropDown.Visible = "on";
                    app.DigitalFilterDesignDropDownLabel.Visible = "on";
    
                    app.DigitalFilterTypeDropDown.Visible = "on";
                    app.DigitalFilterTypeDropDownLabel.Visible = "on";
    
                    app.ApplyDigitalFilterButton.Visible = "on";
                    
                    app.WindowSizeEditField.Visible = "off";
                    app.WindowSizeEditFieldLabel.Visible = "off";

                    app.DigitalBandwidthEditField.Visible = "on";
                    app.DigitalBandwidthEditFieldLabel.Visible = "on";
                    
                    app.WindowTypeDropDown.Visible = "on";
                    app.WindowTypeDropDownLabel.Visible = "on";


                end                
            end

        end

        % Button pushed function: ApplyAnalogFilterButton
        function ApplyAnalogFilterButtonPushed(app, event)
            t = 0:1/app.Fs:length(app.signal)/app.Fs; %Samples to time
            t = t(1:length(t)-1); %0 to end of the vector
            N = length(app.signal);
            f = app.Fs*(0:(N/2))/N;
            f = f(1:end-1); %frequency instead of K

            %convert it from an analog filter to digital to apply to a
            %digital signal using the bilinear transformation
            [app.FilterNumerator, app.FilterDenominator] = bilinear(app.FilterNumerator, app.FilterDenominator, app.Fs);
            
            
            app.beforeFilter = app.signal; %Save the signal before the filter is applied for undo feature
            app.signal = filter(app.FilterNumerator, app.FilterDenominator, app.signal); %apply the filter
            plot(app.FilteredPlot, t, app.signal); %plot output signal
            app.fftsignal = FFTPlease(app.signal); %fft(app.signal);  %plot the output fft of the signal - was using fft function as it is fater than the one made so good for troubleshooting
            stem(app.FilteredPlotFFT, f, abs(app.fftsignal(1:N/2))/2);  %plot the frequencies of the signal
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [app.file, path] = uigetfile({'*.wav'}, 'Select An Audio File');
            if path ~= 0 %if a file was selected
                app.filestatus = 1; %set the global variable filestatus to true, to allow other functions to know
                [app.signal, app.Fs] = audioread(app.file); %Read the file and store the initial signal in global variable along with its sampling frequency
                
                plotSignal(app); %plot the input signal
            else
                app.filestatus = 0;
            end
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recObj = audiorecorder;
            app.file = "RecordedAudio";
            recordblocking(recObj, 5); %Record from microphone for 5 seconds
            app.signal = getaudiodata(recObj); %set input signal to the audio data of the object
            app.Fs = 8000; %Smapling frequency
            plotSignal(app); %plot the input signal
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
                sound(app.signal, app.Fs); %play the signal
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
                clear sound; %stop the sound
        end

        % Value changed function: DigitalFilterDesignDropDown
        function DigitalFilterDesignDropDownValueChanged(app, event)
            value = app.DigitalFilterDesignDropDown.Value;
           if strcmp(value, "Moving Average") %if it is moving average, only show components relevent to moving average
               app.DigitalFilterTypeDropDown.Visible = "off";
               app.DigitalFilterTypeDropDownLabel.Visible = "off";
               app.WindowTypeDropDown.Visible = "off";
               app.WindowTypeDropDownLabel.Visible = "off";
               app.WindowSizeEditField.Visible = "on";
               app.WindowSizeEditFieldLabel.Visible = "on";
              
              app.DigitalBandwidthEditField.Visible = "off";
              app.DigitalBandwidthEditFieldLabel.Visible = "off";

              app.DigitalCutoffFrequencyEditField.Visible = "off";
              app.DigitalCutoffFrequencyEditFieldLabel.Visible = "off";

           else %if it is changed to windowed sinc, only show components relevent to windowed sinc
              app.DigitalFilterTypeDropDown.Visible = "on";
              app.DigitalFilterTypeDropDownLabel.Visible = "on";
              app.WindowTypeDropDown.Visible = "on";
              app.WindowTypeDropDownLabel.Visible = "on";
              app.WindowSizeEditField.Visible = "off";
              app.WindowSizeEditFieldLabel.Visible = "off";

              app.DigitalBandwidthEditField.Visible = "on";
              app.DigitalBandwidthEditFieldLabel.Visible = "on";

              app.DigitalCutoffFrequencyEditField.Visible = "on";
              app.DigitalCutoffFrequencyEditFieldLabel.Visible = "on";


           end
           
        end

        % Button pushed function: PlotDigitalFilterButton
        function PlotDigitalFilterButtonPushed(app, event)
            if (strcmp(app.DigitalFilterDesignDropDown.Value, "Moving Average")) 
                b = (1/app.WindowSizeEditField.Value)*ones(1,app.WindowSizeEditField.Value); %Numerator of the filter
                a = 1; %denominator for FIR is always 1
                [h, w] = freqz(b, a); %digital frequency response 
                app.FilterNumerator= b; %set global variables to apply the filter 
                app.FilterDenominator = a;
                plot(app.PlotMagnitude, 2*pi*w, mag2db(abs(h))); %plot frequency response
                plot(app.PlotPhase, 2*pi*w, rad2deg(angle(h)));
            else%window sinc
                 M = ceil(4/(app.DigitalBandwidthEditField.Value/app.Fs)); %Find number of points needed by 4/BW

                if mod(M, 2) == 1 %Make the number of points even
                        M = M + 1;
                end
                
                %Set the window to be equal in  size as the impulse reponse
                %M. 
                if strcmp(app.WindowTypeDropDown.Value, "Blackman Harris")
                    window = blackmanharris(M)';
                elseif strcmp(app.WindowTypeDropDown.Value, "Rectangular")
                    window = rectwin(M)';
                elseif strcmp(app.WindowTypeDropDown.Value, "Hamming")
                    window = hamming(M)';
                else %Kaiser
                    window = kaiser(M)';
                end 
                
                if strcmp(app.DigitalFilterTypeDropDown.Value, "Low Pass")
                    %If user wants a low pass filter
                    fc = app.DigitalCutoffFrequencyEditField.Value/app.Fs;
                   
                    h = LowPassSincFilter(app, fc, M, window); %Returns impulse response of a low pass filter
                    app.FilterNumerator = h; %impulse response
                    app.FilterDenominator = 1;
                    [app.hb, w] = freqz(h, 1); %frequency response of the digital filter

                    semilogx(app.PlotMagnitude, w*0.5*app.Fs/pi, mag2db(abs(app.hb)));
                    semilogx(app.PlotPhase, w*0.5*app.Fs/pi, rad2deg(angle(app.hb)));
                    
                elseif strcmp(app.DigitalFilterTypeDropDown.Value, "High Pass")
                    fc = app.DigitalCutoffFrequencyEditField.Value/app.Fs;

                    h = LowPassSincFilter(app, fc, M, window);

                    %convert h to -h
                    for i = 1:length(h)
                         h(i) = -h(i);
                    end
                    %make middle point, the highest h(i) value, and add 1
                    %to it
                    h(M/2 + 1) = h(M/2 + 1) + 1;
                    
                    %h is now the impulse response for a high pass filter
                    app.FilterNumerator = h;
                    app.FilterDenominator = 1;
                    [app.hb, w] = freqz(h, 1); %frequency response

                    semilogx(app.PlotMagnitude, w*0.5*app.Fs/pi, mag2db(abs(app.hb)));
                    semilogx(app.PlotPhase, w*0.5*app.Fs/pi, rad2deg(angle(app.hb)));


                elseif strcmp(app.DigitalFilterTypeDropDown.Value, "Band Pass") %Found by convolving low pass and high pass filters at respected frequencies
                    fl = app.DigitalCutoffFrequencyEditField.Value/app.Fs; %High pass cutoff frequency
                    fh = app.SecondDigitalCutoffFrequencyEditField.Value/app.Fs; %Low pass cutoff frequency fh > fl 
                    
                    if (fh < fl) %compare which frequency is higher. make fh the larger frequency
                        temp = fh;
                        fh = fl;
                        fl = temp;
                    end

                    
                    hlpf = LowPassSincFilter(app, fh, M, window); %Low pass filter with higher frequency
                    hhpf = LowPassSincFilter(app, fl, M, window); %High pass filter with lower frequency 
                    
                    %Convert hhpf from a low pass to a high pass filter
                    for i = 1:length(hhpf)
                         hhpf(i) = -hhpf(i);
                    end
                    hhpf(M/2 + 1) = hhpf(M/2 + 1) + 1;

                    %Impulse response for a band pass filter is the
                    %convolution of the the low pass and the high pass
                    hbpf = conv(hhpf, hlpf);

                    app.FilterNumerator = hbpf;
                    app.FilterDenominator = 1;
                    
                    [app.hb, w] = freqz(hbpf, 1); %Frequency response of the band pass

                    semilogx(app.PlotMagnitude, w*0.5*app.Fs/pi, mag2db(abs(app.hb)));
                    semilogx(app.PlotPhase, w*0.5*app.Fs/pi, rad2deg(angle(app.hb)));
                
                elseif strcmp(app.DigitalFilterTypeDropDown.Value, "Band Stop")
                    fl = app.DigitalCutoffFrequencyEditField.Value/app.Fs; %High pass cutoff frequency
                    fh = app.SecondDigitalCutoffFrequencyEditField.Value/app.Fs; %Low pass cutoff frequency fh > fl 
                    
                    if (fh < fl)
                        temp = fh;
                        fh = fl;
                        fl = temp;
                    end

                    
                    hlpf = LowPassSincFilter(app, fl, M, window); %High pass filter with higher frequency
                    hhpf = LowPassSincFilter(app, fh, M, window); %Low pass filter with lower frequency 

                    for i = 1:length(hhpf)
                         hhpf(i) = -hhpf(i);
                    end
                    hhpf(M/2 + 1) = hhpf(M/2 + 1) + 1;

                    hbsf = hhpf + hlpf; %band stop is just the high pass and low pass filters combined

                    app.FilterNumerator = hbsf;
                    app.FilterDenominator = 1;

                    [app.hb, w] = freqz(hbsf, 1); %frequency response
                    semilogx(app.PlotMagnitude, w*0.5*app.Fs/pi, mag2db(abs(app.hb)));
                    semilogx(app.PlotPhase, w*0.5*app.Fs/pi, rad2deg(angle(app.hb)));
                
                end
            end
        end

        % Button pushed function: ApplyDigitalFilterButton
        function ApplyDigitalFilterButtonPushed(app, event)
            app.beforeFilter = app.signal;
            %applies the digital filter to the signal
            app.signal = filter(app.FilterNumerator, app.FilterDenominator, app.signal);
            %plots output signal
            t = 0:1/app.Fs:length(app.signal)/app.Fs;
            t = t(1:length(t)-1);
            N = length(app.signal);
            f = app.Fs*(0:(N/2))/N;
            f = f(1:end-1); 

            plot(app.FilteredPlot, t, app.signal)
            app.fftsignal = FFTPlease(app.signal); %fft(app.signal); %FFTPlease(app.signal);
            stem(app.FilteredPlotFFT, f, abs(app.fftsignal(1:N/2))/2);
        end

        % Value changed function: DigitalFilterTypeDropDown
        function DigitalFilterTypeDropDownValueChanged(app, event)
            value = app.DigitalFilterTypeDropDown.Value;
            
            %if the digital filter is a band pass or band stop it shows
            %necessary components
            if strcmp(value, "Band Pass") || strcmp(value, "Band Stop")
                app.SecondDigitalCutoffFrequencyEditField.Visible = "on";
                app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = "on";
            else
                app.SecondDigitalCutoffFrequencyEditField.Visible = "off";
                app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = "off";
            end

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            %sets the input signal to the signal before the last filter is
            %applied
            app.signal = app.beforeFilter;
            
        end

        % Value changed function: AnalogFilterDesignDropDown
        function AnalogFilterDesignDropDownValueChanged(app, event)
            value = app.AnalogFilterDesignDropDown.Value;
            %if the analog filter design is changed to a chebyshev the
            %stopband attentuation is shown or removed
            if strcmp(value, "Chebyshev I") || strcmp(value, "Chebyshev II")
                app.StopBandAttentuation.Visible = "on"
                app.StopBandAttentuationEditFieldLabel.Visible = "on"
            else 
                app.StopBandAttentuation.Visible = "off"
                app.StopBandAttentuationEditFieldLabel.Visible = "off" 
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %export the file with the new signal
            str = strcat(app.file(1:length(app.file)-4), "New", ".wav"); %new string combining the name of the file and the New and .wav, so audiowrite knows the file type.
            audiowrite(str, app.signal, app.Fs); %write in .wav format
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotMagnitude = uiaxes(app.UIFigure);
            title(app.PlotMagnitude, 'Filter Magnitude Plot')
            xlabel(app.PlotMagnitude, 'Frequency (Hz)')
            ylabel(app.PlotMagnitude, 'Magnitude (dB)')
            zlabel(app.PlotMagnitude, 'Z')
            app.PlotPhase = uiaxes(app.UIFigure);
            title(app.PlotPhase, 'Filter Phase Plot')
            xlabel(app.PlotPhase, 'Frequency (Hz)')
            ylabel(app.PlotPhase, 'Phase (degrees)')
            app.InputSignal = uiaxes(app.UIFigure);
            title(app.InputSignal, 'Input Signal')
            xlabel(app.InputSignal, 'Time')
            ylabel(app.InputSignal, 'Frequency (Hz)')
            zlabel(app.InputSignal, 'Z')
            app.InputSignalFFT = uiaxes(app.UIFigure);
            title(app.InputSignalFFT, 'Input Signal FFT')
            xlabel(app.InputSignalFFT, 'Frequency (Hz)')
            ylabel(app.InputSignalFFT, 'Amplitude')
            zlabel(app.InputSignalFFT, 'Z')
            app.FilteredPlotFFT = uiaxes(app.UIFigure);
            title(app.FilteredPlotFFT, 'FFT of Filtered Input')
            xlabel(app.FilteredPlotFFT, 'Frequency (Hz)')
            ylabel(app.FilteredPlotFFT, 'Amplitude')
            zlabel(app.FilteredPlotFFT, 'Z')
            app.FilteredPlot = uiaxes(app.UIFigure);
            title(app.FilteredPlot, 'Filtered Input Signal')
            xlabel(app.FilteredPlot, 'Time (s)')
            ylabel(app.FilteredPlot, 'Amplitude')
            zlabel(app.FilteredPlot, 'Z')
            app.AnalogOrDigital = uidropdown(app.UIFigure);
            app.AnalogOrDigital.Items = {'Analog', 'Digital', '', ''};
            app.AnalogOrDigital.ValueChangedFcn = createCallbackFcn(app, @AnalogOrDigitalValueChanged, true);
            app.AnalogOrDigital.Value = 'Analog';
            app.PlotAnalogFilterButton = uibutton(app.UIFigure, 'push');
            app.PlotAnalogFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAnalogFilterButtonPushed, true);
            app.PlotAnalogFilterButton.Text = 'Plot Analog Filter';
            app.AnalogOrderLabel = uilabel(app.UIFigure);
            app.AnalogOrderLabel.Text = 'Analog Order';
            app.AnalogOrderSpinner = uispinner(app.UIFigure);
            app.AnalogOrderSpinner.Limits = [1 Inf];
            app.AnalogOrderSpinner.Value = 1;
            app.AnalogFilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalogFilterTypeDropDownLabel.Text = ' Analog Filter Type';
            app.AnalogFilterTypeDropDown = uidropdown(app.UIFigure);
            app.AnalogFilterTypeDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.AnalogFilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalogFilterTypeDropDownValueChanged, true);
            app.AnalogFilterTypeDropDown.Value = 'Low Pass';
            app.AnalogCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.AnalogCutoffFrequencyEditFieldLabel.Text = 'Analog Cutoff Frequency';
            app.AnalogCutoffFrequency = uieditfield(app.UIFigure, 'numeric');
            app.AnalogCutoffFrequency.Limits = [1 Inf];
            app.AnalogCutoffFrequency.Value = 1;
            app.StopBandAttentuationEditFieldLabel = uilabel(app.UIFigure);
            app.StopBandAttentuationEditFieldLabel.Visible = 'off';
            app.StopBandAttentuationEditFieldLabel.Text = 'Stop Band Attentuation';
            app.StopBandAttentuation = uieditfield(app.UIFigure, 'numeric');
            app.StopBandAttentuation.Limits = [1 Inf];
            app.StopBandAttentuation.Visible = 'off';
            app.StopBandAttentuation.Value = 1;
            app.SecondAnalogCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SecondAnalogCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.SecondAnalogCutoffFrequencyEditFieldLabel.Text = 'Second Analog Cutoff Frequency';
            app.SecondAnalogCutoffFrequency = uieditfield(app.UIFigure, 'numeric');
            app.SecondAnalogCutoffFrequency.Limits = [1 Inf];
            app.SecondAnalogCutoffFrequency.Visible = 'off';
            app.SecondAnalogCutoffFrequency.Value = 1;
            app.AnalogFilterDesignDropDownLabel = uilabel(app.UIFigure);
            app.AnalogFilterDesignDropDownLabel.Text = 'Analog Filter Design';
            app.AnalogFilterDesignDropDown = uidropdown(app.UIFigure);
            app.AnalogFilterDesignDropDown.Items = {'Butterworth', 'Bessel', 'Chebyshev I', 'Chebyshev II'};
            app.AnalogFilterDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalogFilterDesignDropDownValueChanged, true);
            app.AnalogFilterDesignDropDown.Value = 'Butterworth';
            app.ApplyAnalogFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyAnalogFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAnalogFilterButtonPushed, true);
            app.ApplyAnalogFilterButton.Text = 'Apply Analog Filter';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.OrLabel = uilabel(app.UIFigure);
            app.OrLabel.Text = 'Or';
            app.RecordAudioButton = uibutton(app.UIFigure, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.ApplyDigitalFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyDigitalFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyDigitalFilterButtonPushed, true);
            app.ApplyDigitalFilterButton.Visible = 'off';
            app.ApplyDigitalFilterButton.Text = 'Apply Digital Filter';
            app.DigitalFilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.DigitalFilterTypeDropDownLabel.Visible = 'off';
            app.DigitalFilterTypeDropDownLabel.Text = 'Digital Filter Type';
            app.DigitalFilterTypeDropDown = uidropdown(app.UIFigure);
            app.DigitalFilterTypeDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.DigitalFilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalFilterTypeDropDownValueChanged, true);
            app.DigitalFilterTypeDropDown.Visible = 'off';
            app.DigitalFilterTypeDropDown.Value = 'Low Pass';
            app.DigitalFilterDesignDropDownLabel = uilabel(app.UIFigure);
            app.DigitalFilterDesignDropDownLabel.Visible = 'off';
            app.DigitalFilterDesignDropDownLabel.Text = 'Digital Filter Design';
            app.DigitalFilterDesignDropDown = uidropdown(app.UIFigure);
            app.DigitalFilterDesignDropDown.Items = {'Moving Average', 'Windowed-Sinc', '', ''};
            app.DigitalFilterDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalFilterDesignDropDownValueChanged, true);
            app.DigitalFilterDesignDropDown.Visible = 'off';
            app.DigitalFilterDesignDropDown.Value = 'Moving Average';
            app.WindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowTypeDropDownLabel.Visible = 'off';
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.UIFigure);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Blackman Harris', 'Hamming', 'Kaiser'};
            app.WindowTypeDropDown.Visible = 'off';
            app.WindowTypeDropDown.Value = 'Blackman Harris';
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Visible = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Visible = 'off';
            app.WindowSizeEditField.Value = 1;
            app.PlotDigitalFilterButton = uibutton(app.UIFigure, 'push');
            app.PlotDigitalFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDigitalFilterButtonPushed, true);
            app.PlotDigitalFilterButton.Visible = 'off';
            app.PlotDigitalFilterButton.Text = 'Plot Digital Filter';
            app.DigitalCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.DigitalCutoffFrequencyEditFieldLabel.Text = 'Digital Cutoff Frequency';
            app.DigitalCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.DigitalCutoffFrequencyEditField.Limits = [1 Inf];
            app.DigitalCutoffFrequencyEditField.Visible = 'off';
            app.DigitalCutoffFrequencyEditField.Value = 1;
            app.SecondDigitalCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SecondDigitalCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.SecondDigitalCutoffFrequencyEditFieldLabel.Text = 'Second Digital Cutoff Frequency';
            app.SecondDigitalCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondDigitalCutoffFrequencyEditField.Limits = [1 Inf];
            app.SecondDigitalCutoffFrequencyEditField.Visible = 'off';
            app.SecondDigitalCutoffFrequencyEditField.Value = 1;
            app.DigitalBandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalBandwidthEditFieldLabel.Visible = 'off';
            app.DigitalBandwidthEditFieldLabel.Text = 'Digital Bandwidth';
            app.DigitalBandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DigitalBandwidthEditField.Limits = [1 Inf];
            app.DigitalBandwidthEditField.Visible = 'off';
            app.DigitalBandwidthEditField.Value = 1;
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project2Redesign_Updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end